classdef covidgraphsapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        TestsAnalysisTab               matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        StartDateDatePickerLabel       matlab.ui.control.Label
        StartDateDatePicker            matlab.ui.control.DatePicker
        EndDateDatePickerLabel         matlab.ui.control.Label
        EndDateDatePicker              matlab.ui.control.DatePicker
        PlotButton                     matlab.ui.control.Button
        CasesAnalysisTab               matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        StartDateDatePicker_2Label     matlab.ui.control.Label
        StartDateDatePicker_2          matlab.ui.control.DatePicker
        EndDateDatePicker_2Label       matlab.ui.control.Label
        EndDateDatePicker_2            matlab.ui.control.DatePicker
        PlotButton_2                   matlab.ui.control.Button
        TestingCategoricalDataFrequencyTab  matlab.ui.container.Tab
        CasesCategoricalDataFreqeuncyTab  matlab.ui.container.Tab
        COVIDsafeBusinessFreqeuncyTab  matlab.ui.container.Tab
        CasesMapAnalysisTab            matlab.ui.container.Tab
        DateSliderLabel                matlab.ui.control.Label
        DateSlider                     matlab.ui.control.Slider
        TimeModeButtonGroup            matlab.ui.container.ButtonGroup
        ShowallcasesButton             matlab.ui.control.RadioButton
        ShowtimespecificButton         matlab.ui.control.RadioButton
        CaseLocationsinNswPanel        matlab.ui.container.Panel
        DateDatePickerLabel            matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        JanuaryLabel                   matlab.ui.control.Label
        OctoberLabel                   matlab.ui.control.Label
        TestsMapAnalysisTab            matlab.ui.container.Tab
        TimeModeButtonGroup_2          matlab.ui.container.ButtonGroup
        ShowallcasesButton_2           matlab.ui.control.RadioButton
        ShowtimespecificButton_2       matlab.ui.control.RadioButton
        TestLocationsinNswPanel        matlab.ui.container.Panel
        JanuaryLabel_2                 matlab.ui.control.Label
        JulyLabel                      matlab.ui.control.Label
        DateSlider_2Label              matlab.ui.control.Label
        DateSlider_2                   matlab.ui.control.Slider
        DateDatePicker_2Label          matlab.ui.control.Label
        DateDatePicker_2               matlab.ui.control.DatePicker
        COVIDsafeBusinessMapAnalysisTab  matlab.ui.container.Tab
        DisplayModeButtonGroup         matlab.ui.container.ButtonGroup
        ShowallbusinessButton          matlab.ui.control.RadioButton
        ShowindustryspecificButton     matlab.ui.control.RadioButton
        COVIDsafeBusinessLocationsinNswPanel  matlab.ui.container.Panel
        IndustryDropDownLabel          matlab.ui.control.Label
        IndustryDropDown               matlab.ui.control.DropDown
        PredictiveModelTab             matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            load testingData.mat
            
            % try adding check boxes for all the suburbs ? and it will only
            % show that suburb ? idk lol stop playin
            
            histogram(app.UIAxes, testingData.datetime)
            
            xlim(app.UIAxes, [app.StartDateDatePicker.Value, app.EndDateDatePicker.Value])
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            load casesData.mat
            
            histogram(app.UIAxes_2, casesData.datetime, 100)
            
            xlim(app.UIAxes_2, [app.StartDateDatePicker_2.Value, app.EndDateDatePicker_2.Value])
        end

        % Value changing function: DateSlider
        function DateSliderValueChanging(app, event)
            load casesData.mat
                        
            changingValue = event.Value;
            
            gx = geoaxes(app.CaseLocationsinNswPanel);
            
            serialdate = interp1([0, 100], [737815, 738092], changingValue);
            
            date = datetime(serialdate, 'ConvertFrom', 'datenum');
            date = dateshift(date, 'start', 'day');
            
            app.DateDatePicker.Value = date;
            
            if app.ShowtimespecificButton.Value
                geoscatter(gx, casesData.latitude(casesData.datetime == date), casesData.longitude(casesData.datetime == date), 10, "red", "filled", "o");
                geolimits(gx, [-38 -27],[141 153]);
            elseif app.ShowallcasesButton.Value
                geoscatter(gx, casesData.latitude, casesData.longitude, 10, "red", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end

        % Value changing function: DateSlider_2
        function DateSlider_2ValueChanging(app, event)
            load testingData.mat
                        
            changingValue = event.Value;
            
            gx = geoaxes(app.TestLocationsinNswPanel);
            
            serialdate = interp1([0, 100], [737791, 737981], changingValue);
            
            date = datetime(serialdate, 'ConvertFrom', 'datenum');
            date = dateshift(date, 'start', 'day');
            
            app.DateDatePicker_2.Value = date;
            
            if app.ShowtimespecificButton_2.Value
                geoscatter(gx, testingData.latitude(testingData.datetime == date), testingData.longitude(testingData.datetime == date), 10, "green", "filled", "o");
                geolimits(gx, [-38 -27],[141 153]);
            elseif app.ShowallcasesButton_2.Value
                geoscatter(gx, testingData.latitude, testingData.longitude, 10, "green", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end

        % Selection changed function: DisplayModeButtonGroup
        function DisplayModeButtonGroupSelectionChanged(app, event)
            load businessesData.mat
            
            selectedButton = app.DisplayModeButtonGroup.SelectedObject;
            
            gx = geoaxes(app.COVIDsafeBusinessLocationsinNswPanel);
            
            if app.ShowallbusinessButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW"), businessesData.longitude(businessesData.business_reported_state == "NSW"), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            elseif app.ShowindustryspecificButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), businessesData.longitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end

        % Value changed function: IndustryDropDown
        function IndustryDropDownValueChanged(app, event)
            load businessesData.mat
            
            gx = geoaxes(app.COVIDsafeBusinessLocationsinNswPanel);
            
            if app.ShowallbusinessButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW"), businessesData.longitude(businessesData.business_reported_state == "NSW"), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            elseif app.ShowindustryspecificButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), businessesData.longitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.TestsAnalysisTab = uitab(app.TabGroup);
            app.TestsAnalysisTab.Title = 'Tests Analysis';
            app.UIAxes = uiaxes(app.TestsAnalysisTab);
            title(app.UIAxes, 'No. COVID-19 Tests Overtime')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'No. COVID-19 Tests')
            app.StartDateDatePickerLabel = uilabel(app.TestsAnalysisTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.TestsAnalysisTab);
            app.StartDateDatePicker.Limits = [datetime([2020 1 1]) datetime([2020 7 9])];
            app.StartDateDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.StartDateDatePicker.Value = datetime([2020 1 1]);
            app.EndDateDatePickerLabel = uilabel(app.TestsAnalysisTab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.TestsAnalysisTab);
            app.EndDateDatePicker.Limits = [datetime([2020 1 1]) datetime([2020 7 9])];
            app.EndDateDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.EndDateDatePicker.Value = datetime([2020 7 9]);
            app.PlotButton = uibutton(app.TestsAnalysisTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CasesAnalysisTab = uitab(app.TabGroup);
            app.CasesAnalysisTab.Title = 'Cases Analysis';
            app.UIAxes_2 = uiaxes(app.CasesAnalysisTab);
            title(app.UIAxes_2, 'No. COVID-19 Cases Overtime')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'No. COVID-19 Cases')
            app.StartDateDatePicker_2Label = uilabel(app.CasesAnalysisTab);
            app.StartDateDatePicker_2Label.Text = 'Start Date';
            app.StartDateDatePicker_2 = uidatepicker(app.CasesAnalysisTab);
            app.StartDateDatePicker_2.Limits = [datetime([2020 1 25]) datetime([2020 10 28])];
            app.StartDateDatePicker_2.DisplayFormat = 'dd/MMM/uuuu';
            app.StartDateDatePicker_2.Value = datetime([2020 1 25]);
            app.EndDateDatePicker_2Label = uilabel(app.CasesAnalysisTab);
            app.EndDateDatePicker_2Label.Text = 'End Date';
            app.EndDateDatePicker_2 = uidatepicker(app.CasesAnalysisTab);
            app.EndDateDatePicker_2.Limits = [datetime([2020 1 25]) datetime([2020 10 28])];
            app.EndDateDatePicker_2.DisplayFormat = 'dd/MMM/uuuu';
            app.EndDateDatePicker_2.Value = datetime([2020 10 28]);
            app.PlotButton_2 = uibutton(app.CasesAnalysisTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.TestingCategoricalDataFrequencyTab = uitab(app.TabGroup);
            app.TestingCategoricalDataFrequencyTab.Title = 'Testing Categorical Data Frequency';
            app.CasesCategoricalDataFreqeuncyTab = uitab(app.TabGroup);
            app.CasesCategoricalDataFreqeuncyTab.Title = 'Cases Categorical Data Freqeuncy';
            app.COVIDsafeBusinessFreqeuncyTab = uitab(app.TabGroup);
            app.COVIDsafeBusinessFreqeuncyTab.Title = 'COVID-safe Business Freqeuncy';
            app.CasesMapAnalysisTab = uitab(app.TabGroup);
            app.CasesMapAnalysisTab.Title = 'Cases Map Analysis';
            app.DateSliderLabel = uilabel(app.CasesMapAnalysisTab);
            app.DateSliderLabel.Text = 'Date';
            app.DateSlider = uislider(app.CasesMapAnalysisTab);
            app.DateSlider.MajorTicks = 0;
            app.DateSlider.ValueChangingFcn = createCallbackFcn(app, @DateSliderValueChanging, true);
            app.DateSlider.MinorTicks = 0;
            app.TimeModeButtonGroup = uibuttongroup(app.CasesMapAnalysisTab);
            app.TimeModeButtonGroup.Title = 'Time Mode';
            app.ShowallcasesButton = uiradiobutton(app.TimeModeButtonGroup);
            app.ShowallcasesButton.Text = 'Show all cases';
            app.ShowallcasesButton.Value = true;
            app.ShowtimespecificButton = uiradiobutton(app.TimeModeButtonGroup);
            app.ShowtimespecificButton.Text = 'Show time specific';
            app.CaseLocationsinNswPanel = uipanel(app.CasesMapAnalysisTab);
            app.CaseLocationsinNswPanel.Title = 'Case Locations in Nsw';
            app.DateDatePickerLabel = uilabel(app.CasesMapAnalysisTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.CasesMapAnalysisTab);
            app.JanuaryLabel = uilabel(app.CasesMapAnalysisTab);
            app.JanuaryLabel.Text = 'January';
            app.OctoberLabel = uilabel(app.CasesMapAnalysisTab);
            app.OctoberLabel.Text = 'October';
            app.TestsMapAnalysisTab = uitab(app.TabGroup);
            app.TestsMapAnalysisTab.Title = 'Tests Map Analysis';
            app.TimeModeButtonGroup_2 = uibuttongroup(app.TestsMapAnalysisTab);
            app.TimeModeButtonGroup_2.Title = 'Time Mode';
            app.ShowallcasesButton_2 = uiradiobutton(app.TimeModeButtonGroup_2);
            app.ShowallcasesButton_2.Text = 'Show all cases';
            app.ShowallcasesButton_2.Value = true;
            app.ShowtimespecificButton_2 = uiradiobutton(app.TimeModeButtonGroup_2);
            app.ShowtimespecificButton_2.Text = 'Show time specific';
            app.TestLocationsinNswPanel = uipanel(app.TestsMapAnalysisTab);
            app.TestLocationsinNswPanel.Title = 'Test Locations in Nsw';
            app.JanuaryLabel_2 = uilabel(app.TestsMapAnalysisTab);
            app.JanuaryLabel_2.Text = 'January';
            app.JulyLabel = uilabel(app.TestsMapAnalysisTab);
            app.JulyLabel.Text = 'July';
            app.DateSlider_2Label = uilabel(app.TestsMapAnalysisTab);
            app.DateSlider_2Label.Text = 'Date';
            app.DateSlider_2 = uislider(app.TestsMapAnalysisTab);
            app.DateSlider_2.MajorTicks = 0;
            app.DateSlider_2.ValueChangingFcn = createCallbackFcn(app, @DateSlider_2ValueChanging, true);
            app.DateSlider_2.MinorTicks = 0;
            app.DateDatePicker_2Label = uilabel(app.TestsMapAnalysisTab);
            app.DateDatePicker_2Label.Text = 'Date';
            app.DateDatePicker_2 = uidatepicker(app.TestsMapAnalysisTab);
            app.COVIDsafeBusinessMapAnalysisTab = uitab(app.TabGroup);
            app.COVIDsafeBusinessMapAnalysisTab.Title = 'COVID-safe Business Map Analysis';
            app.DisplayModeButtonGroup = uibuttongroup(app.COVIDsafeBusinessMapAnalysisTab);
            app.DisplayModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayModeButtonGroupSelectionChanged, true);
            app.DisplayModeButtonGroup.Title = 'Display Mode';
            app.ShowallbusinessButton = uiradiobutton(app.DisplayModeButtonGroup);
            app.ShowallbusinessButton.Text = 'Show all business';
            app.ShowallbusinessButton.Value = true;
            app.ShowindustryspecificButton = uiradiobutton(app.DisplayModeButtonGroup);
            app.ShowindustryspecificButton.Text = 'Show industry specific';
            app.COVIDsafeBusinessLocationsinNswPanel = uipanel(app.COVIDsafeBusinessMapAnalysisTab);
            app.COVIDsafeBusinessLocationsinNswPanel.Title = 'COVID-safe Business Locations in Nsw';
            app.IndustryDropDownLabel = uilabel(app.COVIDsafeBusinessMapAnalysisTab);
            app.IndustryDropDownLabel.Text = 'Industry';
            app.IndustryDropDown = uidropdown(app.COVIDsafeBusinessMapAnalysisTab);
            app.IndustryDropDown.Items = {'Agriculture', 'Arts and entertainment', 'Auctions and open houses', 'Beauty, nail, waxing, tanning and hairdressing salons', 'Betting agencies ', 'Caravan and Camping ', 'Community centres and Hall ', 'Community sport for adults ', 'Community sport for kids ', 'Construction ', 'Drive-In ', 'Education & training ', 'Food processing ', 'Gyms ', 'Healthcare ', 'Hotels and accommodation ', 'Indoor recreation facilities ', 'Legal and justice ', 'Libraries ', 'Museums and galleries ', 'Non-food markets ', 'Office environment ', 'Other industry ', 'Places of worship ', 'Pubs, clubs, bars, breweries and casinos ', 'Restaurants and Cafes ', 'Retail and Grocery ', 'Sports, recreation and gyms ', 'Tradespeople ', 'Transport and freight ', 'Weddings and Funerals ', 'Zoos, reptile parks and aquariums '};
            app.IndustryDropDown.ValueChangedFcn = createCallbackFcn(app, @IndustryDropDownValueChanged, true);
            app.IndustryDropDown.Value = 'Agriculture';
            app.PredictiveModelTab = uitab(app.TabGroup);
            app.PredictiveModelTab.Title = 'Predictive Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covidgraphsapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end